classdef FDD_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FermiEdgeFittingUIFigure     matlab.ui.Figure
        MultiplybyFDDPanel           matlab.ui.container.Panel
        MultiplybyFDDButton          matlab.ui.control.Button
        TemperatureEditField_2       matlab.ui.control.NumericEditField
        TemperatureEditField_2Label  matlab.ui.control.Label
        FitwithFermiDirecDistributionPanel  matlab.ui.container.Panel
        ResolutioneVEditField        matlab.ui.control.NumericEditField
        ResolutioneVEditFieldLabel   matlab.ui.control.Label
        SlopeEditField               matlab.ui.control.NumericEditField
        SlopeEditFieldLabel          matlab.ui.control.Label
        FitModeButtonGroup           matlab.ui.container.ButtonGroup
        Mod6Button                   matlab.ui.control.RadioButton
        Mod5Button                   matlab.ui.control.RadioButton
        Mod4Button                   matlab.ui.control.RadioButton
        Mod3Button                   matlab.ui.control.RadioButton
        Mod2Button                   matlab.ui.control.RadioButton
        Mod1Button                   matlab.ui.control.RadioButton
        BackgroundEditField          matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel     matlab.ui.control.Label
        OffsetRenormaliseButton      matlab.ui.control.Button
        OffsetButton                 matlab.ui.control.Button
        TemperatureKEditField        matlab.ui.control.NumericEditField
        TemperatureKEditFieldLabel   matlab.ui.control.Label
        E_FeVEditField               matlab.ui.control.NumericEditField
        E_FLabel                     matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        EmaxEditField_2              matlab.ui.control.NumericEditField
        EmaxEditField_2Label         matlab.ui.control.Label
        EminEditField_2              matlab.ui.control.NumericEditField
        EminEditField_2Label         matlab.ui.control.Label
        GetFitAreaButton             matlab.ui.control.Button
        GetEDCfromFigurePanel        matlab.ui.container.Panel
        YScaleButtonGroup            matlab.ui.container.ButtonGroup
        LogButton                    matlab.ui.control.RadioButton
        LinearButton                 matlab.ui.control.RadioButton
        KmaxEditField                matlab.ui.control.NumericEditField
        KmaxEditFieldLabel           matlab.ui.control.Label
        KminEditField                matlab.ui.control.NumericEditField
        KminEditFieldLabel           matlab.ui.control.Label
        EmaxEditField                matlab.ui.control.NumericEditField
        EmaxEditFieldLabel           matlab.ui.control.Label
        EminEditField                matlab.ui.control.NumericEditField
        EminEditFieldLabel           matlab.ui.control.Label
        GetEDCButton                 matlab.ui.control.Button
        FigNumEditField              matlab.ui.control.NumericEditField
        FigNumEditFieldLabel         matlab.ui.control.Label
        DataPanel                    matlab.ui.container.Panel
        PlotButton                   matlab.ui.control.Button
        RefreshButton                matlab.ui.control.Button
        ListBox                      matlab.ui.control.ListBox
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        E_new % Description
        EDC_new
        E_sel
        EDC_sel
        EDC_renormalised
    end
    
    methods (Access = private)
        
        function f = FermiDiracDistribution(app, Energy, Temperature,FermiLevel,Resolution)

            kb = 8.617333262E-5;
            sigma = Resolution/(2*sqrt(2*log(2)));
            RCFDD = @(x) 1/(sigma*sqrt(2*pi))*exp(-0.5*(x./sigma)^2).*((1)./(exp((Energy-FermiLevel-x)./(kb*Temperature))+1));
            f = integral(RCFDD, -5*sigma, 5*sigma, 'ArrayValued', true);
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            vars = evalin('base','who');
            app.ListBox.Items = vars;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            dataname = app.ListBox.Value;
            PlotSlices(dataname,'x');
        end

        % Button pushed function: GetEDCButton
        function GetEDCButtonPushed(app, event)
            FigureNum = app.FigNumEditField.Value;
            rect = getrect(figure(FigureNum));
            x1 = rect(1);
            x2 = rect(1)+rect(3);
            y1 = rect(2);
            y2 = rect(2)+rect(4);
            app.EminEditField.Value = y1;
            app.EmaxEditField.Value = y2;
            app.KminEditField.Value = x1;
            app.KmaxEditField.Value = x2;

            dataObjs = findobj(figure(FigureNum),'-property','XData');
            xd = dataObjs(1).XData;
            yd = dataObjs(1).YData;
            cd = dataObjs(1).CData';

            [~,xn1] = min(abs(xd - x1));
            [~,xn2] = min(abs(xd - x2));
            [~,yn1] = min(abs(yd - y1));
            [~,yn2] = min(abs(yd - y2));

            app.E_new = yd(yn1:yn2);
            app.EDC_new = mean(cd(xn1:xn2,yn1:yn2),1);

            app.E_new = reshape(app.E_new,[length(app.E_new), 1]);
            app.EDC_new = reshape(app.EDC_new,[length(app.EDC_new), 1]);

            plot(app.UIAxes,app.E_new,app.EDC_new,'DisplayName','EDC of selected area');
            title(app.UIAxes,'EDC and Fit');
            xlabel(app.UIAxes,'E-E_F (eV)');
            ylabel(app.UIAxes,'Sum');
            axis(app.UIAxes,'tight');
            legend(app.UIAxes);

            if app.LogButton.Value ==1
                set(app.UIAxes, 'YScale', 'log');
            end

            
        end

        % Button pushed function: GetFitAreaButton
        function GetFitAreaButtonPushed(app, event)
            rect = getrect(app.UIAxes);
            x1 = rect(1);
            x2 = rect(1)+rect(3);

            app.EminEditField_2.Value = x1;
            app.EmaxEditField_2.Value = x2;

            [~,xn1] = min(abs(app.E_new - x1));
            [~,xn2] = min(abs(app.E_new - x2));

            app.E_sel = app.E_new(xn1:xn2);
            app.EDC_sel = app.EDC_new(xn1:xn2);
%             app.EDC_sel = app.EDC_sel';
%             app.E_sel = reshape(app.E_sel,[length(app.E_sel),1]);
%             app.EDC_sel = reshape(app.EDC_sel,[length(app.E_sel),1]);


            plot(app.UIAxes,app.E_new,app.EDC_new,'DisplayName','EDC of selected area');
            title(app.UIAxes,'EDC and Fit');
            xlabel(app.UIAxes,'E-E_F (eV)');
            ylabel(app.UIAxes,'Sum');
            axis(app.UIAxes,'tight');

            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.E_sel,app.EDC_sel,"LineWidth",2,'DisplayName','Fitting area');
            hold(app.UIAxes,'off');

            selectedButton = app.FitModeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Mod 1'
                    % Fit mode 1: standard Fermi-Direc Distribution
                    kb = 8.617333262E-5;
                    fitfun = fittype( @(a,b,c,x) a./(exp((x-b)./(kb*c))+1));
                    x0 = [app.EDC_sel(1), 0, 30];
                    [fitted_curve,gof] = fit(app.E_sel,app.EDC_sel,fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.E_FeVEditField.Value = coeffvals(2);
                    app.TemperatureKEditField.Value = coeffvals(3);
                    app.BackgroundEditField.Value = 0;
        
                    app.EDC_renormalised = (app.EDC_new).*(exp((app.E_new-coeffvals(2))./(kb*coeffvals(3)))+1);
                    app.EDC_renormalised(app.EDC_renormalised < 0) = 0;
                    app.EDC_renormalised(app.EDC_renormalised > app.EDC_new(1)*2) = app.EDC_new(1)*2;

                    FC = fitted_curve(app.E_new);

                case 'Mod 2'
                    % Fit mode 2: including the background noise

                    kb = 8.617333262E-5;
                    fitfun = fittype( @(a,b,c,d,x) log(a./(exp((x-b)./(kb*c))+1) +d));
                    x0 = [app.EDC_sel(1), 0, 30,0];
                    [fitted_curve,gof] = fit(app.E_sel,log(app.EDC_sel),fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.E_FeVEditField.Value = coeffvals(2);
                    app.TemperatureKEditField.Value = coeffvals(3);
                    app.BackgroundEditField.Value = coeffvals(4);
        
                    app.EDC_renormalised = (app.EDC_new - coeffvals(4)).*(exp((app.E_new-coeffvals(2))./(kb*coeffvals(3)))+1);
                    app.EDC_renormalised(app.EDC_renormalised < 0) = 0;
                    app.EDC_renormalised(app.EDC_renormalised > app.EDC_new(1)*2) = app.EDC_new(1)*2;

                    hold(app.UIAxes,'on');
                    plot(app.UIAxes,app.E_new,exp(fitted_curve(app.E_new)),'k--');
                    plot(app.UIAxes,app.E_new,app.EDC_renormalised,'r--');
                    xline(app.UIAxes,app.E_FeVEditField.Value,'b-.');
                    hold(app.UIAxes,'off');

                    FC = exp(fitted_curve(app.E_new));

                case 'Mod 3'
                    % Fit mode 3: including the background noise, linear background

                    kb = 8.617333262E-5;

                    % 1st FIT
                    fitfun = fittype( @(a,b,c,d,x) log(a./(exp((x-b)./(kb*c))+1) +d));
                    x0 = [app.EDC_sel(1), 0, 30,0];
                    [fitted_curve,gof] = fit(app.E_sel,log(app.EDC_sel),fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.E_FeVEditField.Value = coeffvals(2);
                    app.TemperatureKEditField.Value = coeffvals(3);
                    app.BackgroundEditField.Value = coeffvals(4);
        
                    % 2nd FIT
                    fitfun = fittype( @(a,b,c,d,g,x) log((a + g.*(x-b))./(exp((x-b)./(kb*c))+1) +d));
                    x0 = [coeffvals(1), coeffvals(2), coeffvals(3),coeffvals(4), 0];
                    [fitted_curve,gof] = fit(app.E_sel,log(app.EDC_sel),fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.E_FeVEditField.Value = coeffvals(2);
                    app.TemperatureKEditField.Value = coeffvals(3);
                    app.BackgroundEditField.Value = coeffvals(4);
                    app.SlopeEditField.Value = coeffvals(5);


                    app.EDC_renormalised = (app.EDC_new - coeffvals(4)).*(exp((app.E_new-coeffvals(2))./(kb*coeffvals(3)))+1);
                    app.EDC_renormalised(app.EDC_renormalised < 0) = 0;
                    app.EDC_renormalised(app.EDC_renormalised > app.EDC_new(1)*2) = app.EDC_new(1)*2;

                    FC = exp(fitted_curve(app.E_new));

                case 'Mod 4'
                    % Fit mode 4: Resolution-Convoluted Fermi-Dirac
                    % Distribution
                    fun = @app.FermiDiracDistribution;
                    fitfun = fittype( @(Amplitude,FermiLevel,Temperature,Resolution,x) Amplitude*fun(x, Temperature,FermiLevel,Resolution));
                    x0 = [app.EDC_sel(1), 0, 30, 0.03];
                    [fitted_curve,gof] = fit(app.E_sel,app.EDC_sel,fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.E_FeVEditField.Value = coeffvals(2);
                    app.TemperatureKEditField.Value = coeffvals(3);
                    app.BackgroundEditField.Value = 0;
                    app.ResolutioneVEditField.Value = coeffvals(4);
        
                    app.EDC_renormalised = app.EDC_new./fitted_curve(app.E_new)*coeffvals(1);
                    app.EDC_renormalised(app.EDC_renormalised < 0) = 0;
                    app.EDC_renormalised(app.EDC_renormalised > app.EDC_new(1)*2) = app.EDC_new(1)*2;

                    FC = fitted_curve(app.E_new);

                case 'Mod 5'
                    % Fit mode 5: Resolution-Convoluted Fermi-Dirac
                    % Distribution with linear background and constant noise

                    % 1st FIT
                    kb = 8.617333262E-5;
                    fitfun = fittype( @(a,b,c,d,x) log(a./(exp((x-b)./(kb*c))+1) +d));
                    x0 = [app.EDC_sel(1), 0, 30,0];
                    [fitted_curve,gof] = fit(app.E_sel,log(app.EDC_sel),fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.E_FeVEditField.Value = coeffvals(2);
                    app.TemperatureKEditField.Value = coeffvals(3);
                    app.BackgroundEditField.Value = coeffvals(4);

                    % 2nd FIT
                    fun = @app.FermiDiracDistribution;
                    fitfun = fittype( @(Amplitude,Slope,FermiLevel,Temperature,Resolution,x) ((Amplitude+Slope*(x-FermiLevel)).* ...
                        fun(x, Temperature,FermiLevel,Resolution))+app.BackgroundEditField.Value);
                    x0 = [app.AmplitudeEditField.Value,1, app.E_FeVEditField.Value, app.TemperatureKEditField.Value, 0.03];
                    [fitted_curve,gof] = fit(app.E_sel,app.EDC_sel,fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.SlopeEditField.Value = coeffvals(2);
                    app.E_FeVEditField.Value = coeffvals(3);
                    app.TemperatureKEditField.Value = coeffvals(4);
                    app.ResolutioneVEditField.Value = coeffvals(5);
        
                    app.EDC_renormalised = (app.EDC_new-app.BackgroundEditField.Value)./ ...
                        fun(app.E_new, app.TemperatureKEditField.Value,app.E_FeVEditField.Value,app.ResolutioneVEditField.Value);
                    app.EDC_renormalised(app.EDC_renormalised < 0) = 0;
                    app.EDC_renormalised(app.EDC_renormalised > app.EDC_new(1)*2) = app.EDC_new(1)*2;

                    FC = fitted_curve(app.E_new);

                case 'Mod 6'
                    % Fit mode 6: Resolution-Convoluted Fermi-Dirac
                    % Distribution with linear background and constant noise
                    % holding Temperature

                    % 1st FIT
                    kb = 8.617333262E-5;
                    fitfun = fittype( @(a,b,c,d,x) log(a./(exp((x-b)./(kb*c))+1) +d));
                    x0 = [app.EDC_sel(1), 0, 30,0];
                    [fitted_curve,gof] = fit(app.E_sel,log(app.EDC_sel),fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.E_FeVEditField.Value = coeffvals(2);
                    app.BackgroundEditField.Value = coeffvals(4);

                    % 2nd FIT
                    fun = @app.FermiDiracDistribution;
                    fitfun = fittype( @(Amplitude,Slope,FermiLevel,Resolution,x) ((Amplitude+Slope*(x-FermiLevel)).* ...
                        fun(x, app.TemperatureKEditField.Value,FermiLevel,Resolution))+app.BackgroundEditField.Value);
                    x0 = [app.AmplitudeEditField.Value,1, app.E_FeVEditField.Value, 0.03];
                    [fitted_curve,gof] = fit(app.E_sel,app.EDC_sel,fitfun,'StartPoint',x0);
                    coeffvals = coeffvalues(fitted_curve);
        
                    app.AmplitudeEditField.Value = coeffvals(1);
                    app.SlopeEditField.Value = coeffvals(2);
                    app.E_FeVEditField.Value = coeffvals(3);
                    app.ResolutioneVEditField.Value = coeffvals(4);
        
                    app.EDC_renormalised = (app.EDC_new-app.BackgroundEditField.Value)./ ...
                        fun(app.E_new, app.TemperatureKEditField.Value,app.E_FeVEditField.Value,app.ResolutioneVEditField.Value);
                    app.EDC_renormalised(app.EDC_renormalised < 0) = 0;
                    app.EDC_renormalised(app.EDC_renormalised > app.EDC_new(1)*2) = app.EDC_new(1)*2;

                    FC = fitted_curve(app.E_new);
            end

            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.E_new,FC,'k--','DisplayName','Fitted Curve');
            plot(app.UIAxes,app.E_new,app.EDC_renormalised,'r--','DisplayName','Renormalised EDC');
            xline(app.UIAxes,app.E_FeVEditField.Value,'b-.','DisplayName','Fermi Level');
            hold(app.UIAxes,'off');


        end

        % Button pushed function: OffsetButton
        function OffsetButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data = evalin('base',dataname);
            
            if ndims(data.value) == 2
                data.y = data.y - app.E_FeVEditField.Value;
            elseif ndims(data.value) == 3
                data.z = data.z - app.E_FeVEditField.Value;
            end
            assignin('base',[dataname '_offset'],data);
        end

        % Button pushed function: OffsetRenormaliseButton
        function OffsetRenormaliseButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data = evalin('base',dataname);

            if ndims(data.value) == 2
                data.y = data.y - app.E_FeVEditField.Value;
                EE = transpose(repmat(data.y(:), 1,length(data.x)));
            elseif ndims(data.value) == 3
                data.z = data.z - app.E_FeVEditField.Value;
                EE = permute(repmat(data.z, 1,length(data.x),length(data.y)),[2 3 1]);
            end
            
            kb = 8.617333262E-5;

            selectedButton = app.FitModeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Mod 1'
                    FDD = exp(EE./(kb* app.TemperatureKEditField.Value))+1;
                    data.value = data.value .*FDD;
                case 'Mod 2'
                    FDD = exp(EE./(kb* app.TemperatureKEditField.Value))+1;
                    data.value = (data.value - app.BackgroundEditField.Value) .*FDD;
                case 'Mod 3'
                    FDD = exp(EE./(kb* app.TemperatureKEditField.Value))+1;
                    data.value = (data.value - app.BackgroundEditField.Value) .*FDD;
                case 'Mod 4'
                    FDD = app.FermiDiracDistribution(EE, app.TemperatureKEditField.Value,0,app.ResolutioneVEditField.Value);
                    data.value = data.value ./FDD;
                case 'Mod 5'
                    FDD = app.FermiDiracDistribution(EE, app.TemperatureKEditField.Value,0,app.ResolutioneVEditField.Value);
                    data.value = (data.value - app.BackgroundEditField.Value) ./FDD;
                case 'Mod 6'
                    FDD = app.FermiDiracDistribution(EE, app.TemperatureKEditField.Value,0,app.ResolutioneVEditField.Value);
                    data.value = (data.value - app.BackgroundEditField.Value) ./FDD;
            end

            data.value(data.value < 0) = 0;
            condition = (data.value > app.EDC_new(1)*8) & (EE > 0);
            data.value(condition) = app.EDC_new(1)*8;

            assignin('base',[dataname '_FDD'],data);
        end

        % Selection changed function: YScaleButtonGroup
        function YScaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.YScaleButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Linear')
                set(app.UIAxes, 'YScale', 'linear');
            else
                set(app.UIAxes, 'YScale', 'log');
            end
        end

        % Button pushed function: MultiplybyFDDButton
        function MultiplybyFDDButtonPushed(app, event)
            dataname = app.ListBox.Value;
            data = evalin('base',dataname);

            EE = transpose(repmat(data.y, 1,length(data.x)));
            kb = 8.617333262E-5;

            FDD = exp(EE./(kb* app.TemperatureEditField_2.Value))+1;
            data.value = data.value./FDD;

            assignin('base',[dataname '_xFDD'],data);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FermiEdgeFittingUIFigure = uifigure('Visible', 'off');
            app.FermiEdgeFittingUIFigure.Name = 'Fermi Edge Fitting';
            app.UIAxes = uiaxes(app.FermiEdgeFittingUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataPanel = uipanel(app.FermiEdgeFittingUIFigure);
            app.DataPanel.Title = 'Data';
            app.ListBox = uilistbox(app.DataPanel);
            app.RefreshButton = uibutton(app.DataPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.PlotButton = uibutton(app.DataPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GetEDCfromFigurePanel = uipanel(app.FermiEdgeFittingUIFigure);
            app.GetEDCfromFigurePanel.Title = 'Get EDC from Figure';
            app.FigNumEditFieldLabel = uilabel(app.GetEDCfromFigurePanel);
            app.FigNumEditFieldLabel.Text = 'Fig. Num';
            app.FigNumEditField = uieditfield(app.GetEDCfromFigurePanel, 'numeric');
            app.GetEDCButton = uibutton(app.GetEDCfromFigurePanel, 'push');
            app.GetEDCButton.ButtonPushedFcn = createCallbackFcn(app, @GetEDCButtonPushed, true);
            app.GetEDCButton.Text = 'Get EDC';
            app.EminEditFieldLabel = uilabel(app.GetEDCfromFigurePanel);
            app.EminEditFieldLabel.Text = 'E min';
            app.EminEditField = uieditfield(app.GetEDCfromFigurePanel, 'numeric');
            app.EmaxEditFieldLabel = uilabel(app.GetEDCfromFigurePanel);
            app.EmaxEditFieldLabel.Text = 'E max';
            app.EmaxEditField = uieditfield(app.GetEDCfromFigurePanel, 'numeric');
            app.KminEditFieldLabel = uilabel(app.GetEDCfromFigurePanel);
            app.KminEditFieldLabel.Text = 'K min';
            app.KminEditField = uieditfield(app.GetEDCfromFigurePanel, 'numeric');
            app.KmaxEditFieldLabel = uilabel(app.GetEDCfromFigurePanel);
            app.KmaxEditFieldLabel.Text = 'K max';
            app.KmaxEditField = uieditfield(app.GetEDCfromFigurePanel, 'numeric');
            app.YScaleButtonGroup = uibuttongroup(app.GetEDCfromFigurePanel);
            app.YScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YScaleButtonGroupSelectionChanged, true);
            app.YScaleButtonGroup.Title = 'Y Scale';
            app.LinearButton = uiradiobutton(app.YScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogButton = uiradiobutton(app.YScaleButtonGroup);
            app.LogButton.Text = 'Log';
            app.FitwithFermiDirecDistributionPanel = uipanel(app.FermiEdgeFittingUIFigure);
            app.FitwithFermiDirecDistributionPanel.Title = 'Fit with Fermi-Direc Distribution';
            app.GetFitAreaButton = uibutton(app.FitwithFermiDirecDistributionPanel, 'push');
            app.GetFitAreaButton.ButtonPushedFcn = createCallbackFcn(app, @GetFitAreaButtonPushed, true);
            app.GetFitAreaButton.Text = 'Get Fit Area';
            app.EminEditField_2Label = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.EminEditField_2Label.Text = 'E min';
            app.EminEditField_2 = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.EmaxEditField_2Label = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.EmaxEditField_2Label.Text = 'E max';
            app.EmaxEditField_2 = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.E_FLabel = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.E_FLabel.Text = 'Δ E_F (eV)';
            app.E_FeVEditField = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.E_FeVEditField.Editable = 'off';
            app.TemperatureKEditFieldLabel = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.TemperatureKEditFieldLabel.Text = 'Temperature (K)';
            app.TemperatureKEditField = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.OffsetButton = uibutton(app.FitwithFermiDirecDistributionPanel, 'push');
            app.OffsetButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetButtonPushed, true);
            app.OffsetButton.Text = 'Offset';
            app.OffsetRenormaliseButton = uibutton(app.FitwithFermiDirecDistributionPanel, 'push');
            app.OffsetRenormaliseButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetRenormaliseButtonPushed, true);
            app.OffsetRenormaliseButton.Text = 'Offset & Renormalise';
            app.BackgroundEditFieldLabel = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.BackgroundEditField = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.FitModeButtonGroup = uibuttongroup(app.FitwithFermiDirecDistributionPanel);
            app.FitModeButtonGroup.Title = 'Fit Mode';
            app.Mod1Button = uiradiobutton(app.FitModeButtonGroup);
            app.Mod1Button.Tooltip = {'Standard Fermi-Dirac Distribution'};
            app.Mod1Button.Text = 'Mod 1';
            app.Mod1Button.Value = true;
            app.Mod2Button = uiradiobutton(app.FitModeButtonGroup);
            app.Mod2Button.Tooltip = {'Considering both Fermi-Dirac Distribution and background noise'};
            app.Mod2Button.Text = 'Mod 2';
            app.Mod3Button = uiradiobutton(app.FitModeButtonGroup);
            app.Mod3Button.Tooltip = {'Fermi-Dirac Distribution with linear background and also background noise'};
            app.Mod3Button.Text = 'Mod 3';
            app.Mod4Button = uiradiobutton(app.FitModeButtonGroup);
            app.Mod4Button.Tooltip = {'Resolution-Convoluted Fermi-Dirac Distribution'};
            app.Mod4Button.Text = 'Mod 4';
            app.Mod5Button = uiradiobutton(app.FitModeButtonGroup);
            app.Mod5Button.Tooltip = {'Resolution-Convoluted Fermi-Dirac Distribution (with linear background and noise)'};
            app.Mod5Button.Text = 'Mod 5';
            app.Mod6Button = uiradiobutton(app.FitModeButtonGroup);
            app.Mod6Button.Tooltip = {'Resolution-Convoluted Fermi-Dirac Distribution (with linear background and noise, holding Temperature)'};
            app.Mod6Button.Text = 'Mod 6';
            app.SlopeEditFieldLabel = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.ResolutioneVEditFieldLabel = uilabel(app.FitwithFermiDirecDistributionPanel);
            app.ResolutioneVEditFieldLabel.Text = 'Resolution (eV)';
            app.ResolutioneVEditField = uieditfield(app.FitwithFermiDirecDistributionPanel, 'numeric');
            app.MultiplybyFDDPanel = uipanel(app.FermiEdgeFittingUIFigure);
            app.MultiplybyFDDPanel.Title = 'Multiply by FDD';
            app.TemperatureEditField_2Label = uilabel(app.MultiplybyFDDPanel);
            app.TemperatureEditField_2Label.Text = 'Temperature';
            app.TemperatureEditField_2 = uieditfield(app.MultiplybyFDDPanel, 'numeric');
            app.MultiplybyFDDButton = uibutton(app.MultiplybyFDDPanel, 'push');
            app.MultiplybyFDDButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplybyFDDButtonPushed, true);
            app.MultiplybyFDDButton.Text = 'Multiply by FDD';
            % Show the figure after all components are created
            app.FermiEdgeFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FDD_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FermiEdgeFittingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FermiEdgeFittingUIFigure)
        end
    end
end